classdef SNOMApproachApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveChangesButton         matlab.ui.control.Button
        ShrinkFactorSpinner       matlab.ui.control.Spinner
        ShrinkFactorSpinnerLabel  matlab.ui.control.Label
        FindcutoffButton          matlab.ui.control.Button
        EditApproachCurveLabel    matlab.ui.control.Label
        PositionSpinner           matlab.ui.control.Spinner
        PositionSpinnerLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        FigHandle; AxesHandle; PlotHandleL; PlotHandleR; % Description
        pos;
    end
    
    properties (Access = public)
        CallingApp; % Description
        ACurve;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.ACurve = app.CallingApp.ApproachCurve;
            app.FigHandle = figure; app.AxesHandle = axes(app.FigHandle);
            yyaxis left
            app.PlotHandleL = plot(app.AxesHandle,app.ACurve.M1A);
            yyaxis right
            app.PlotHandleR = plot(app.AxesHandle,app.ACurve.O2A);
        end

        % Button pushed function: FindcutoffButton
        function FindcutoffButtonPushed(app, event)
            [~,app.pos]=min(diff(diff(smoothdata(app.ACurve.M1A,'movmean',10))));
            figure, plot(diff(diff(smoothdata(app.ACurve.M1A,'movmean',10))));
            app.XlineHandle = xline(app.pos);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PositionSpinnerLabel = uilabel(app.UIFigure);
            app.PositionSpinnerLabel.Text = 'Position';
            app.PositionSpinner = uispinner(app.UIFigure);
            app.PositionSpinner.RoundFractionalValues = 'on';
            app.EditApproachCurveLabel = uilabel(app.UIFigure);
            app.EditApproachCurveLabel.Text = 'Edit Approach Curve';
            app.FindcutoffButton = uibutton(app.UIFigure, 'push');
            app.FindcutoffButton.ButtonPushedFcn = createCallbackFcn(app, @FindcutoffButtonPushed, true);
            app.FindcutoffButton.Text = 'Find cutoff';
            app.ShrinkFactorSpinnerLabel = uilabel(app.UIFigure);
            app.ShrinkFactorSpinnerLabel.Text = 'Shrink Factor';
            app.ShrinkFactorSpinner = uispinner(app.UIFigure);
            app.SaveChangesButton = uibutton(app.UIFigure, 'push');
            app.SaveChangesButton.Text = 'Save Changes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNOMApproachApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end