classdef SNOMHyperSpectralApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DatatypeSwitch                matlab.ui.control.Switch
        DatatypeSwitchLabel           matlab.ui.control.Label
        AlignmapsPanel                matlab.ui.container.Panel
        LoadProjectButton             matlab.ui.control.Button
        LastFOffsetYEditField         matlab.ui.control.NumericEditField
        LastFOffsetYEditFieldLabel    matlab.ui.control.Label
        LastFOffsetXEditField         matlab.ui.control.NumericEditField
        LastFOffsetXEditFieldLabel    matlab.ui.control.Label
        NextButton                    matlab.ui.control.Button
        AlignButton                   matlab.ui.control.Button
        ExtractSpectrumPanel          matlab.ui.container.Panel
        TextFileCheckBox              matlab.ui.control.CheckBox
        useCheckBox                   matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        AddReferenceButton            matlab.ui.control.Button
        RemoveButton                  matlab.ui.control.Button
        SpectraDropDown               matlab.ui.control.DropDown
        SpectraDropDownLabel          matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        ROITypeDropDown               matlab.ui.control.DropDown
        ROITypeDropDownLabel          matlab.ui.control.Label
        NewSpectrumButton             matlab.ui.control.Button
        ProcessMapsButton             matlab.ui.control.Button
        WaveNumberField               matlab.ui.control.EditField
        ScrollMapsSpinner             matlab.ui.control.Spinner
        ScrollMapsSpinnerLabel        matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        HyperSpectralProcessingLabel  matlab.ui.control.Label
        ContextMenu                   matlab.ui.container.ContextMenu
        SaveSpectrumMenu              matlab.ui.container.Menu
        HyperspectralMenu             matlab.ui.container.Menu
        AllSpectraMenu                matlab.ui.container.Menu
        SaveProjectMenu               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        
        HyperCube; TopoCube; alignedCube;% 3d array to store the hyperspectral data; 3rd diemnsion for the multiwavelength collection
        folderMain; subFolders;
        FigHandle; AxHandle; ImHandle; FigHandleTopo; AxHandleTopo; ImHandleTopo;
        FigHSpectrum; AxHSpectrum; ImHSpectrum;
        CallingApp;
        CurrentMapNumber;
        WaveNumber;
        AxLim; AxLimTopo;
        SpectrumROI; SpectrumMask;
        Spectrum;
        FOffset; CrossRectPos; CrossRectROI;

        Spectra; newROI; newSpectrumVals; NSpectra; NSpectraAll;

        RefIdx;

    end
    
    methods (Access = private)
        
        function [] = UpDateColorMap(app) 
            app.AxHandle.CLim = [min(min(min(app.HyperCube))), max(max(max(app.HyperCube)))];
            app.AxHandle.Colormap=colorcet(app.CallingApp.ColorCETmapEditField.Value);
            colorbar;
            refresh;
        end
        function [] = UpDateMapTopo(app,M) 
            app.ImHandleTopo.CData = M;
            app.AxHandleTopo.CLim = [min(min(M)), max(max(M))];
            app.AxHandleTopo.Colormap=colorcet('L01');
            colorbar;
            refresh;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;

            f = {'PosX','PosY','ROI','Wavenumber','Spectrum','Handle'};
            f{2,1} = {};
            app.Spectra = struct(f{:});
            app.SpectraDropDown.ItemsData = [];
            app.SpectraDropDown.Items = {};

            app.NSpectra = 0;
            app.NSpectraAll = 0;

            app.FigHandle = figure;
            app.AxHandle = axes(app.FigHandle);
            
            try
                indc = regexp(app.CallingApp.filepath,'\');
                app.folderMain = app.CallingApp.filepath(1:indc(end-1));
                app.subFolders = SNOMListSubfolders(app.folderMain);
            catch
            end
            
            if app.CallingApp.AutomaticcorrectionsCheckBox.Value == 1
                for i=1:length(app.subFolders)
                    fileList = dir(fullfile([app.folderMain app.subFolders{i}], ['*.','gsf']));
                    app.HyperCube(:,:,i) = SNOMGetMeasFromFolder(fileList,app.CallingApp.dataType);
                    app.TopoCube(:,:,i) = SNOMGetMeasFromFolder(fileList,' Z raw');
                    app.WaveNumber(i) = SNOMGetWavenumberFromFileName(app.subFolders{i});
                end
                    app.EditField.Value = [num2str(size(app.subFolders,3)) ' map was loaded'];
            else
                app.HyperCube = app.CallingApp.HyperCube;
                app.TopoCube = app.CallingApp.TopoCube;
                app.WaveNumber = app.CallingApp.HyperWNs;
                app.alignedCube = zeros(size(app.HyperCube));
            end
            
            app.ScrollMapsSpinner.Limits = [1 size(app.HyperCube,3)];
            app.EditField.Value = [num2str(size(app.HyperCube,3)) ' map was loaded'];

            app.ImHandle = imagesc(app.AxHandle,app.HyperCube(:,:,1));
            axis(app.AxHandle, 'tight' , 'equal')
            app.AxLim = [min(min(min(app.HyperCube))), max(max(max(app.HyperCube)))];
            app.AxHandle.CLim = app.AxLim;
            
            [app.WaveNumber,indxs] = sort(app.WaveNumber);
            app.HyperCube = app.HyperCube(:,:,indxs);
            app.TopoCube = app.TopoCube(:,:,indxs);

        end

        % Value changing function: ScrollMapsSpinner
        function ScrollMapsSpinnerValueChanging(app, event)
            app.CurrentMapNumber = event.Value;
            app.WaveNumberField.Value = num2str(app.WaveNumber(app.CurrentMapNumber));
            app.ImHandle.CData = app.HyperCube(:,:,app.CurrentMapNumber);
            app.AxHandle.CLim = app.AxLim;
            app.AxHandle.Colormap = app.CallingApp.AxHandle.Colormap;
        end

        % Button pushed function: ProcessMapsButton
        function ProcessMapsButtonPushed(app, event)
            for j = 1:size(app.HyperCube,3)
                
                for i=1:length(app.CallingApp.ProcessRecord)
                    
                    name = app.CallingApp.ProcessRecord(i).process;
                    option = app.CallingApp.ProcessRecord(i).option;
                    switch name
                        case 'Polyfit'
                            polytype = ['poly' option(1) option(2)];
                            PolyBGZ = SNOMFitWithMask(app.CallingApp.gridX,app.CallingApp.gridY,app.HyperCube(:,:,j),app.CallingApp.MaskContainer(:,:,str2double(option(3))),polytype);
                            if app.CallingApp.DataTypeBoolean == 1
                                app.HyperCube(:,:,j) = app.HyperCube(:,:,j)./PolyBGZ;
                            elseif app.CallingApp.DataTypeBoolean == 0
                                app.HyperCube(:,:,j) = app.HyperCube(:,:,j)-PolyBGZ;
                            end
                            
                        case 'Alignrows'
                            if app.CallingApp.DataTypeBoolean == 1
                                switch app.CallingApp.ProcessRecord(i).option
                                    case 'median'
                                        app.HyperCube(:,:,j) = SNOMalignrows(app.HyperCube(:,:,j),'Amp');
                                    case 'meadiandiff'
                                        app.HyperCube(:,:,j) = SNOMalignrowsMedDiff(app.HyperCube(:,:,j),'Amp');
                                end
                            elseif app.CallingApp.DataTypeBoolean == 0
                                switch app.CallingApp.ProcessRecord(i).option
                                    case 'median'
                                        app.HyperCube(:,:,j) = SNOMalignrows(app.HyperCube(:,:,j),'Phase');
                                    case 'mediandiff'
                                        app.HyperCube(:,:,j) = SNOMalignrowsMedDiff(app.HyperCube(:,:,j),'Phase');
                                end
                            end
                    end
                    
                end
                
            end
            app.AxLim = [min(min(min(app.HyperCube))), max(max(max(app.HyperCube)))];
            app.AxHandle.CLim = app.AxLim;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            [~,idx] = ismember(app.SpectraDropDown.Value,app.SpectraDropDown.ItemsData);
            plotidx = find([app.Spectra.Handle]==app.SpectraDropDown.ItemsData(idx));
            if app.useCheckBox.Value == 0
                figure; 
                plot(app.Spectra(plotidx).Wavenumber,app.Spectra(plotidx).Spectrum)
            else
                figure;
                value = app.DatatypeSwitch.Value;
                switch value
                    case 'Amplitude'
                        plot(app.Spectra(plotidx).Wavenumber,app.Spectra(plotidx).Spectrum./app.Spectra(app.RefIdx).Spectrum)
                    case 'Phase'
                        plot(app.Spectra(plotidx).Wavenumber,app.Spectra(plotidx).Spectrum-app.Spectra(app.RefIdx).Spectrum)
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.HyperSpectralProcessingButton.Enable='on';
            try 
                close(app.FigHandle)
            catch
                warning('SNOMapp Figure window was already closed');
            end
            delete(app)
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            app.NextButton.Enable = 'on';
            app.FigHandleTopo = figure;
            app.AxHandleTopo = axes(app.FigHandleTopo);
            app.ImHandleTopo = imagesc(app.AxHandleTopo,app.TopoCube(:,:,1));
            axis(app.AxHandleTopo, 'tight' , 'equal')
            app.AxLimTopo = [min(min(app.TopoCube(:,:,1))), max(max(app.TopoCube(:,:,1)))];
            app.AxHandleTopo.Colormap=colorcet('L01');
            app.AxHandleTopo.CLim = app.AxLimTopo;
            colorbar;
            
            AlignROI = drawcircle(app.AxHandleTopo);
            app.FOffset(1,1:2) = [0,0]; %Full offset x,y
            app.LastFOffsetXEditField.Value = app.FOffset(1,1);
            app.LastFOffsetYEditField.Value = app.FOffset(1,2);
            wait(AlignROI)

            app.CrossRectPos = [1,1,size(app.TopoCube(:,:,1),2),size(app.TopoCube(:,:,1),1)];
            
            for i=2:size(app.TopoCube,3)
                %Calculate the shift and put alignement ROI in the new
                %position
                [yshift,xshift] = SNOMGetMapShift(app.TopoCube(:,:,i),app.TopoCube(:,:,i-1));
                XOffset = xshift; YOffset = yshift;
                PosROIstart = AlignROI.Position;

                %Update figure with the next measurement and the new roi
                %position
                UpDateMapTopo(app,app.TopoCube(:,:,i));
                AlignROI.Position = AlignROI.Position+[XOffset,YOffset];

                %Wait for the user to manually validat roi position and hit
                %NEXT on the UI
                uiwait(app.UIFigure)

                %Get the ROI position and calculate the full offset from it
                %and store it in app.FOffset
                PosROIend = AlignROI.Position;
                app.FOffset(i,1:2) = PosROIend-PosROIstart;

                %Dispaly offset values to UI
                app.LastFOffsetXEditField.Value = app.FOffset(i,1);
                app.LastFOffsetYEditField.Value = app.FOffset(i,2);

                %Calculate the crossection rectangle of the two shifted
                %measurement area
                app.CrossRectPos = ShiftedRectCrossSection(app.CrossRectPos,[-1*app.FOffset(i,1:2),size(app.TopoCube(:,:,i),2),size(app.TopoCube(:,:,i),1)]);

            end
            
            %Draw croossection rectangle
            UpDateMapTopo(app,app.TopoCube(:,:,1));
            app.CrossRectROI = drawrectangle('Position',app.CrossRectPos,'StripeColor','green');
            app.CrossRectROI.InteractionsAllowed = 'none';
            app.CrossRectROI.FaceAlpha = 0;

            %UI notifications
            app.NextButton.Enable = 'off';
            app.EditField.Value = ['Alignement was done for ',num2str(size(app.FOffset,1)),' images'];
            delete(AlignROI)

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            uiresume(app.UIFigure)
        end

        % Button pushed function: NewSpectrumButton
        function NewSpectrumButtonPushed(app, event)
            app.NewSpectrumButton.Enable = 'off';
            val = app.ROITypeDropDown.Value;
            switch val
                case '1'
                    app.newROI = drawcircle(app.AxHandleTopo,'StripeColor','red','DrawingArea',app.CrossRectPos);
                case '2'
                    app.newROI = drawpoint(app.AxHandleTopo,'StripeColor','red','DrawingArea',app.CrossRectPos);
                case '3'
                    app.newROI = drawrectangle(app.AxHandleTopo,'StripeColor','red','DrawingArea',app.CrossRectPos);
                case '4'
                    app.newROI = drawpolygon(app.AxHandleTopo,'StripeColor','red','DrawingArea',app.CrossRectPos);
            end
            wait(app.newROI)
            
            BasePosition = app.newROI.Position;

%             tfig = figure;
%             tax = axes(tfig);

            for i=1:size(app.TopoCube,3)
                MeasTemp = app.HyperCube(:,:,i);
%                 app.newROI.Position = BasePosition+app.FOffset(i,1:2);
%                 imagesc(tax,app.HyperCube(:,:,i));
                switch val
                    case '1'
                        app.newROI.Center = app.newROI.Center+app.FOffset(i,1:2);
%                         drawcircle(tax,"Center",app.newROI.Center);
                        Mask = createMask(app.newROI);
                        app.newSpectrumVals(i) = mean(MeasTemp((Mask(:))));
                    case '2'
                        app.newSpectrumVals(i) = MeasTemp(round(app.newROI.Position(1)),round(app.newROI.Position(1)));
                end
                app.ImHandleTopo.CData = app.TopoCube(:,:,i);
                pause(0.1)
            end
            
            app.ImHandleTopo.CData = app.TopoCube(:,:,1);
            app.newROI.Position = BasePosition;
            app.newROI.InteractionsAllowed = 'none';

            app.FigHSpectrum = figure;
            app.AxHSpectrum = axes(app.FigHSpectrum);
            app.ImHSpectrum = plot(app.AxHSpectrum,app.WaveNumber,app.newSpectrumVals);

            app.AddButton.Enable = 'on';
            app.CancelButton.Enable = 'on';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.newROI)
            app.NewSpectrumButton.Enable = 'on';
            app.CancelButton.Enable = 'off';
            app.AddButton.Enable = 'off';
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.NSpectra = app.NSpectra + 1;
            app.NSpectraAll = app.NSpectraAll + 1;
            
            SpectraIdx = length(app.Spectra)+1;
            app.Spectra(SpectraIdx).Wavenumber = app.WaveNumber;
            app.Spectra(SpectraIdx).Spectrum = app.newSpectrumVals;
            app.Spectra(SpectraIdx).PosX = app.newROI.Position(1);
            app.Spectra(SpectraIdx).PosY = app.newROI.Position(2);
            app.Spectra(SpectraIdx).ROI = copyobj(app.newROI,app.AxHandleTopo);
            app.Spectra(SpectraIdx).ROI.Label = num2str(app.NSpectraAll);
            app.Spectra(SpectraIdx).ROI.StripeColor = 'none';
            app.Spectra(SpectraIdx).ROI.InteractionsAllowed = 'none';
            app.Spectra(SpectraIdx).Handle = app.NSpectraAll;
            delete(app.newROI);
            
            app.SpectraDropDown.ItemsData(end+1) = app.Spectra(SpectraIdx).Handle;
            app.SpectraDropDown.Items{end+1} = num2str(app.Spectra(SpectraIdx).Handle);

            app.NewSpectrumButton.Enable = 'on';
            app.CancelButton.Enable = 'off';
            app.AddButton.Enable = 'off';
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.NSpectra = app.NSpectra - 1;
            [~,idx] = ismember(app.SpectraDropDown.Value,app.SpectraDropDown.ItemsData);
            deleteidx = find([app.Spectra.Handle]==app.SpectraDropDown.ItemsData(idx));
            %Delete from dropdown list
            app.SpectraDropDown.Items(idx) = [];
            app.SpectraDropDown.ItemsData(idx) = [];
            %Delete roi and delete from spectra object
            delete(app.Spectra(deleteidx).ROI)
            app.Spectra(deleteidx) = [];

            if deleteidx == app.RefIdx
                app.useCheckBox.Value = 0;
                app.useCheckBox.Enable = 'off';
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [~,idx] = ismember(app.SpectraDropDown.Value,app.SpectraDropDown.ItemsData);
            saveidx = find([app.Spectra.Handle]==app.SpectraDropDown.ItemsData(idx));
            
            if app.TextFileCheckBox.Value == 1
                    try
                        [app.SaveName,app.SaveFolder] = uiputfile('.txt');
                        T = table(app.Spectra(saveidx).Wavenumber', app.Spectra(saveidx).Spectrum','VariableNames',{'WN','S'});
                        writetable(T, [app.SaveFolder,app.SaveName],'Delimiter','\t');
                    catch
                    end
            else
                try
                    assignin('base',['LaserSpectrum',num2str(saveidx)],app.Spectra(saveidx));
                catch
                end
            end
        end

        % Button pushed function: AddReferenceButton
        function AddReferenceButtonPushed(app, event)
            [~,idx] = ismember(app.SpectraDropDown.Value,app.SpectraDropDown.ItemsData);
            app.RefIdx = find([app.Spectra.Handle]==app.SpectraDropDown.ItemsData(idx));
            app.Spectra(app.RefIdx).ROI.Label = [num2str(app.NSpectraAll) '-REF'];
            app.Spectra(app.RefIdx).ROI.Color = 'red';
            if app.useCheckBox.Enable == 0
                app.useCheckBox.Enable = 1;
            else
            end
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
%             assignin('base','TopoMap',app.TopoCube(:,:,1));
%             assignin('base','OpticMap',app.HyperCube(:,:,1));
%             assignin('base','CrossRectPos',app.CrossRectPos);
%             assignin('base','Spectra',app.Spectra);
%             assignin('base','FOffset',app.FOffset);
            CrossRectPos = app.CrossRectPos;
            Spectra = app.Spectra;
            FOffset = app.FOffset;
%             uisave({'CrossRectPos','Spectra','FOffset'},'ProjectFile');
            filter = {'*.mat'};
            [filename,filepath] = uiputfile(filter);
            save([filepath,filename],'CrossRectPos','Spectra','FOffset');
            app.EditField.Value = ['Project saved under name: ',[filepath,filename]];
        end

        % Button pushed function: LoadProjectButton
        function LoadProjectButtonPushed(app, event)
            [fname, fpath] = uigetfile({'*.mat','MATLAB files'},'Select Offset files');
            temp = load([fpath, fname]);
            app.CrossRectPos = temp.CrossRectPos;
            app.FOffset = temp.FOffset;
            app.Spectra = temp.Spectra;

            app.FigHandleTopo = figure;
            app.AxHandleTopo = axes(app.FigHandleTopo);
            app.ImHandleTopo = imagesc(app.AxHandleTopo,app.TopoCube(:,:,1));
            axis(app.AxHandleTopo, 'tight' , 'equal')
            app.AxLimTopo = [min(min(app.TopoCube(:,:,1))), max(max(app.TopoCube(:,:,1)))];
            app.AxHandleTopo.Colormap=colorcet('L01');
            app.AxHandleTopo.CLim = app.AxLimTopo;
            colorbar;

            app.CrossRectROI = drawrectangle('Position',app.CrossRectPos,'StripeColor','green');
            app.CrossRectROI.InteractionsAllowed = 'none';
            app.CrossRectROI.FaceAlpha = 0;

            for i=1:length(app.Spectra)
%                 thisROI = app.Spectra(i).ROI;
                app.Spectra(i).ROI = drawcircle(app.AxHandleTopo,'Center',app.Spectra(i).ROI.Center,'Radius',app.Spectra(i).ROI.Radius,'Label',app.Spectra(i).ROI.Label,'InteractionsAllowed','none');
                app.SpectraDropDown.ItemsData(i) = app.Spectra(i).Handle;
                app.SpectraDropDown.Items{i} = num2str(app.Spectra(i).Handle);
            end
        end

        % Menu selected function: HyperspectralMenu
        function HyperspectralMenuSelected(app, event)
            shift = [0,0];
%             figh = figure;
%             axh = axes(figh);
            app.alignedCube = zeros(round(app.CrossRectPos(4)),round(app.CrossRectPos(3)),size(app.HyperCube,3));
            cropRect = app.CrossRectPos;
            cropRect(3) = cropRect(3)-1; cropRect(4) = cropRect(4)-1;
            for i = 1:size(app.HyperCube,3)
                shift = shift-app.FOffset(i,1:2);
                temp = imtranslate(app.HyperCube(:,:,i),shift,'FillValues',0);
                app.alignedCube(:,:,i) = imcrop(temp,cropRect);
%                 imagesc(axh,app.alignedCube(:,:,i))
%                 pause(0.1);
            end
            %convert to 2d matrix
            if app.TextFileCheckBox.Value == 0
                assignin('base','AlignedHyperCube',app.alignedCube);
            else
                M = createFilefrom3dmatrix(app.alignedCube);
                %save to file
                filter = {'*.txt'};
                [filename,filepath] = uiputfile(filter);
                try
                    writeCytoXYZ1file(M,app.WaveNumber,filepath,filename);
                    app.EditField.Value = ['XYZ1 file saved: ',[filepath filename]];
                catch
                    app.EditField.Value = 'File saving was unsuccesfull';
                end
            end

        end

        % Menu selected function: AllSpectraMenu
        function AllSpectraMenuSelected(app, event)
           if app.TextFileCheckBox.Value == 1
                    try
                        [SaveName,SaveFolder] = uiputfile('.txt');
                        vnames = {'WN'};
                        M = zeros(length(app.WaveNumber),length(app.Spectra)+1);
                        M(:,1) = app.WaveNumber';
                        for i = 1:length(app.Spectra)
                            M(:,i+1) = app.Spectra(i).Spectrum';
                            vnames(i+1) = {['S',num2str(app.Spectra(i).Handle)]};
                        end
                        T = array2table(M, 'VariableNames',vnames);
                        assignin('base','T',T);
                        writetable(T,[SaveFolder,SaveName],'Delimiter','\t');
                        app.EditField.Value = ['All spectra were save into text file:',SaveFolder,SaveName];
                    catch
                        uialert(app.UIFigure,"Something went wrong during saving! Please try again!","Unsuccessful saving!")
                    end
            else
                try
                    assignin('base','LaserSpectra',app.Spectra);
                catch
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HyperSpectralProcessingLabel = uilabel(app.UIFigure);
            app.HyperSpectralProcessingLabel.Text = 'Hyper Spectral Processing';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.ScrollMapsSpinnerLabel = uilabel(app.UIFigure);
            app.ScrollMapsSpinnerLabel.Text = 'Scroll Maps';
            app.ScrollMapsSpinner = uispinner(app.UIFigure);
            app.ScrollMapsSpinner.ValueChangingFcn = createCallbackFcn(app, @ScrollMapsSpinnerValueChanging, true);
            app.ScrollMapsSpinner.Limits = [1 Inf];
            app.ScrollMapsSpinner.RoundFractionalValues = 'on';
            app.ScrollMapsSpinner.Value = 1;
            app.WaveNumberField = uieditfield(app.UIFigure, 'text');
            app.WaveNumberField.Editable = 'off';
            app.ProcessMapsButton = uibutton(app.UIFigure, 'push');
            app.ProcessMapsButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessMapsButtonPushed, true);
            app.ProcessMapsButton.Enable = 'off';
            app.ProcessMapsButton.Text = 'Process Maps';
            app.ExtractSpectrumPanel = uipanel(app.UIFigure);
            app.ExtractSpectrumPanel.Title = 'Extract Spectrum';
            app.NewSpectrumButton = uibutton(app.ExtractSpectrumPanel, 'push');
            app.NewSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @NewSpectrumButtonPushed, true);
            app.NewSpectrumButton.Text = 'New Spectrum';
            app.ROITypeDropDownLabel = uilabel(app.ExtractSpectrumPanel);
            app.ROITypeDropDownLabel.Text = 'ROI Type';
            app.ROITypeDropDown = uidropdown(app.ExtractSpectrumPanel);
            app.ROITypeDropDown.Items = {'Circle', 'Point', 'Rectangle', 'Polygon'};
            app.ROITypeDropDown.ItemsData = {'1', '2', '3', '4'};
            app.ROITypeDropDown.Enable = 'off';
            app.ROITypeDropDown.Value = '1';
            app.PlotButton = uibutton(app.ExtractSpectrumPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AddButton = uibutton(app.ExtractSpectrumPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.CancelButton = uibutton(app.ExtractSpectrumPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.SpectraDropDownLabel = uilabel(app.ExtractSpectrumPanel);
            app.SpectraDropDownLabel.Text = 'Spectra';
            app.SpectraDropDown = uidropdown(app.ExtractSpectrumPanel);
            app.SpectraDropDown.Items = {};
            app.SpectraDropDown.Value = {};
            app.RemoveButton = uibutton(app.ExtractSpectrumPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.AddReferenceButton = uibutton(app.ExtractSpectrumPanel, 'push');
            app.AddReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AddReferenceButtonPushed, true);
            app.AddReferenceButton.Text = 'Add Reference';
            app.SaveButton = uibutton(app.ExtractSpectrumPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.useCheckBox = uicheckbox(app.ExtractSpectrumPanel);
            app.useCheckBox.Enable = 'off';
            app.useCheckBox.Text = 'use';
            app.TextFileCheckBox = uicheckbox(app.ExtractSpectrumPanel);
            app.TextFileCheckBox.Text = 'Text File';
            app.AlignmapsPanel = uipanel(app.UIFigure);
            app.AlignmapsPanel.Title = 'Align maps';
            app.AlignButton = uibutton(app.AlignmapsPanel, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Text = 'Align';
            app.NextButton = uibutton(app.AlignmapsPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.LastFOffsetXEditFieldLabel = uilabel(app.AlignmapsPanel);
            app.LastFOffsetXEditFieldLabel.Text = 'Last FOffset X:';
            app.LastFOffsetXEditField = uieditfield(app.AlignmapsPanel, 'numeric');
            app.LastFOffsetYEditFieldLabel = uilabel(app.AlignmapsPanel);
            app.LastFOffsetYEditFieldLabel.Text = 'Last FOffset Y:';
            app.LastFOffsetYEditField = uieditfield(app.AlignmapsPanel, 'numeric');
            app.LoadProjectButton = uibutton(app.AlignmapsPanel, 'push');
            app.LoadProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectButton.Text = 'Load Project';
            app.DatatypeSwitchLabel = uilabel(app.UIFigure);
            app.DatatypeSwitchLabel.Text = 'Datatype';
            app.DatatypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.DatatypeSwitch.Items = {'Amplitude', 'Phase'};
            app.DatatypeSwitch.Value = 'Phase';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.SaveSpectrumMenu = uimenu(app.ContextMenu);
            app.SaveSpectrumMenu.Text = 'Save Spectrum';
            app.HyperspectralMenu = uimenu(app.SaveSpectrumMenu);
            app.HyperspectralMenu.MenuSelectedFcn = createCallbackFcn(app, @HyperspectralMenuSelected, true);
            app.HyperspectralMenu.Text = 'Hyperspectral';
            app.AllSpectraMenu = uimenu(app.SaveSpectrumMenu);
            app.AllSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @AllSpectraMenuSelected, true);
            app.AllSpectraMenu.Text = 'All Spectra';
            app.SaveProjectMenu = uimenu(app.ContextMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            % Assign app.ContextMenu
            app.SaveButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNOMHyperSpectralApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end