classdef SNOMapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelfreferencePanel             matlab.ui.container.Panel
        ApplySelfReferencce            matlab.ui.control.Button
        ChooseharmonicDropDown         matlab.ui.control.DropDown
        ChooseharmonicDropDownLabel    matlab.ui.control.Label
        NormalizeButton                matlab.ui.control.Button
        DataTypeSwitch_2               matlab.ui.control.Switch
        DataTypeSwitch_2Label          matlab.ui.control.Label
        OtheroperationsPanel           matlab.ui.container.Panel
        RotatePhaseButton              matlab.ui.control.Button
        PhaseRotateEditField           matlab.ui.control.NumericEditField
        InvertDataButton               matlab.ui.control.Button
        GeneralPanel                   matlab.ui.container.Panel
        SaveDataButton                 matlab.ui.control.Button
        CropMapButton                  matlab.ui.control.Button
        DisplaymappropertiesPanel      matlab.ui.container.Panel
        RangeMaxEditField              matlab.ui.control.NumericEditField
        RangeMaxEditFieldLabel         matlab.ui.control.Label
        RangeMinEditField              matlab.ui.control.NumericEditField
        RangeMinEditFieldLabel         matlab.ui.control.Label
        SetMapButton                   matlab.ui.control.Button
        ColorCETmapEditField           matlab.ui.control.EditField
        ColorCETmapEditFieldLabel      matlab.ui.control.Label
        ScaleBarCheckBox               matlab.ui.control.CheckBox
        ScalebarEditField              matlab.ui.control.EditField
        ScalebarnmEditFieldLabel       matlab.ui.control.Label
        HyperspectraldataprocessPanel  matlab.ui.container.Panel
        DeleteButton                   matlab.ui.control.Button
        ShowButton                     matlab.ui.control.Button
        DataSetViewerDropDown          matlab.ui.control.DropDown
        DataSetViewerDropDownLabel     matlab.ui.control.Label
        ClearDataSetButton             matlab.ui.control.Button
        SaveDataSetButton              matlab.ui.control.Button
        LoadDataSetButton              matlab.ui.control.Button
        DataTypeSwitch                 matlab.ui.control.Switch
        DataTypeSwitchLabel            matlab.ui.control.Label
        AutomaticcorrectionsCheckBox   matlab.ui.control.CheckBox
        AddtoDataSetButton             matlab.ui.control.Button
        RecordcorrectionsButton        matlab.ui.control.StateButton
        HyperSpectralProcessingButton  matlab.ui.control.Button
        RecondButton                   matlab.ui.control.Button
        PolaritoninterferometryButton  matlab.ui.control.Button
        ApproachCurveButton            matlab.ui.control.Button
        ReloadButton                   matlab.ui.control.Button
        PhaseTopoLamp                  matlab.ui.control.Lamp
        PhaseTopoLampLabel             matlab.ui.control.Label
        AmpLamp                        matlab.ui.control.Lamp
        AmpLabel                       matlab.ui.control.Label
        LineProfilePanel               matlab.ui.container.Panel
        ProfileAvgSpinner              matlab.ui.control.Spinner
        AvgSpinner_2Label              matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        DrawProfileButton              matlab.ui.control.Button
        AlignRowsPanel                 matlab.ui.container.Panel
        ApplyRowCorrectionButton       matlab.ui.control.Button
        AlignButton                    matlab.ui.control.Button
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        PolynomialBGremovalPanel       matlab.ui.container.Panel
        RemoveBGButton                 matlab.ui.control.Button
        FitButton                      matlab.ui.control.Button
        ydegreeEditField               matlab.ui.control.NumericEditField
        ydegreeEditFieldLabel          matlab.ui.control.Label
        xdegreeEditField               matlab.ui.control.NumericEditField
        xdegreeEditFieldLabel          matlab.ui.control.Label
        MaskingPanel                   matlab.ui.container.Panel
        InvertButton                   matlab.ui.control.Button
        ShapeDropDown                  matlab.ui.control.DropDown
        ShapeDropDownLabel             matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        SubtractButton                 matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        DrawMaskButton                 matlab.ui.control.Button
        ActivateCheckBox               matlab.ui.control.CheckBox
        PlanelevelingPanel             matlab.ui.container.Panel
        ShowBGButton                   matlab.ui.control.Button
        SelectPointsButton             matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        LoadFileButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        %Data arrays
        Meas; MeasData; MeasDataBackUp; dataType; DataTypeBoolean;
        XAxData; YAxData; XPixel; YPixel;

        %Plane levelling variable
        PlanePointsX; PlanePointsY; PlanePoints;
        
        %Data file
        filename; filepath; CurrentPathName; LastFilename; LastFilepath;
        
        %Image handles
        FigHandle; AxHandle; ImHandle; 
        ProfileFigHandle; ProfileAxHandle;
        colorMap;
        
        %Mask variables
        Mask; MaskROI;
        PointMask; PointROI1; PointROI2; PointROI3;
        
        %Background arrays
        gridX; gridY;
        PolyBGZ; PlaneBG;
        
        %Line Profile variables
        ProfileROI; LineProfile; LineMarker1; LineMarker2; ProfilePosition;
        
        %Row alignment variable
        RowCorrectedTemp;
        
        %Struct for Approach curve
        ApproachCurve = struct('Depth',[],'Z',[],'M1P',[],'O1A',[],'O1P',[],'O2A',[],'O2P',[],'O3A',[],'O3P',[],'O4A',[],'O4P',[]);
        
        ApproachApp; ApproachEdited;
        
        TopoCube; HyperCube; HyperSpectralApp;
        HyperWNs; TopoWNs;
        IsInTopoCube; IsInHyperCube;
        
        ProcessRecord; MaskContainer;
        scalebarh; scalebartexth;
%         CropROI;
    end
    
    methods (Access = public)
        
        function ProfileROIChange(app,src,event)
%             app.LineProfile = improfile(app.MeasData,app.ProfileROI.Position(:,1),app.ProfileROI.Position(:,2));
            app.ProfilePosition = app.ProfileROI.Position;
            [app.LineProfile,posMark1,posMark2] = SNOMExctractAvgProfile(app.MeasData,app.ProfilePosition,[min(app.XAxData) max(app.XAxData)],[min(app.YAxData) max(app.YAxData)],app.ProfileAvgSpinner.Value);
            
            app.LineMarker1.Position = posMark1;
            app.LineMarker2.Position = posMark2;
            
            plot(app.ProfileAxHandle, app.LineProfile)
        end
        
        function PlanePointsChange(app,src,event)
%             app.LineProfile = improfile(app.MeasData,app.ProfileROI.Position(:,1),app.ProfileROI.Position(:,2));
            [app.LineProfile,posMark1,posMark2] = SNOMExctractAvgProfile(app.MeasData,app.ProfileROI,app.ProfileAvgSpinner.Value);
            
            app.LineMarker1.Position = posMark1;
            app.LineMarker2.Position = posMark2;
            
            plot(app.ProfileAxHandle, app.LineProfile)
        end
    end
    
    methods (Access = private)
        
        function [] = UpDateFigure(app) 
            try
                app.ImHandle.XData = app.XAxData;
                app.ImHandle.YData = app.YAxData;
                app.ImHandle.CData = app.MeasData;
                app.AxHandle.CLim = [min(app.ImHandle.CData(:)),max(app.ImHandle.CData(:))];
                app.RangeMaxEditField.Value = app.AxHandle.CLim(2);
                app.RangeMinEditField.Value = app.AxHandle.CLim(1);
                colorbar;
                refresh;
            catch
                uialert(app.UIFigure,'No proper data to display','Nothing to display')
            end
        end
        
        function [] = PlotTempFigure(app,DataToPlot)
            figh = figure;
            axh = axes(figh);
            imh = imagesc(axh,'XData',app.XAxData,'YData',app.YAxData,'CData',DataToPlot);
%             app.ImHandle = imagesc(app.AxHandle,app.MeasData);
            axis(axh, 'tight' , 'equal','ij');
            xlabel('x position [µm]')
            ylabel('y position [µm]')
            axh.CLim = [min(imh.CData(:)),max(imh.CData(:))];
            axh.Colormap = app.AxHandle.Colormap;
            colorbar;
        end

        function [] = ReDrawData(app)
            if ishghandle(app.FigHandle)
            else
                app.FigHandle = figure;
                app.AxHandle = axes(app.FigHandle);
            end
            cla(app.AxHandle);
            app.ImHandle = imagesc(app.AxHandle,'XData',app.XAxData,'YData',app.YAxData,'CData',app.MeasData);
            axis(app.AxHandle,'tight','equal','ij');
            xlabel('x position [µm]')
            ylabel('y position [µm]')
            try
                app.AxHandle.CLim = [min(app.ImHandle.CData(:)),max(app.ImHandle.CData(:))];
            catch
                if isnan(mean(app.ImHandle.CData(:)))
                    app.AxHandle.CLim = [0.1*mean(app.ImHandle.CData(:)),log(0)];
                else
                    app.AxHandle.CLim = [0.1*mean(app.ImHandle.CData(:)),1.9*mean(app.ImHandle.CData(:))];
                end
            end
            dtype = SNOMCheckDataType(app.filename);
            if ~isempty(regexp(dtype,' Z')) == 1 || ~isempty(regexp(dtype,'-Z')) == 1
                app.AxHandle.Colormap=colorcet('GRAY');
            elseif ~isempty(regexp(dtype,'P')) == 1
                app.AxHandle.Colormap=colorcet('NEADD');
            elseif ~isempty(regexp(dtype,'A')) == 1
                app.AxHandle.Colormap=colorcet('HEAT');
            else
                app.colorMap = colormap(app.AxHandle,jet(1024));
            end
            app.RangeMaxEditField.Value = app.AxHandle.CLim(2);
            app.RangeMinEditField.Value = app.AxHandle.CLim(1);
            colorbar;
            refresh(app.FigHandle)
        end
        
        %Recondition huge outlier datapoint by replacing them with the
        %median value of the whole image
        function [] = ReconditionData(app)
            medmed = median(median(app.MeasData));
            trshldvalue = medmed*1e3;
            for i=1:size(app.MeasData,2)
                for j=1:size(app.MeasData,1)
                    if app.MeasData(j,i) > trshldvalue
                        app.MeasData(j,i) = medmed;
                    end
                end
            end
            UpDateFigure(app);
        end
        
        function DeleteMarkers(app)
            delete(app.LineMarker1);
            delete(app.LineMarker2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FigHandle = figure;
            app.AxHandle = axes(app.FigHandle);
%             set(app.FigHandle,'CloseRequestFcn','code to execute');
            %Initialize a zero struct for Process Record
            fieldnames = {'process','option'};
            strargs = fieldnames(:).';
            strargs{2, 1} = {}; 
            app.ProcessRecord = struct(strargs{:});
            app.CurrentPathName = userpath;
            
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
%             [app.filename,app.filepath]=uigetfile({'*.*','All Files'},'Select Data File');

            [app.filename,app.filepath]=uigetfile({'*.gsf;*.dump','NeaFILEs'},'Select Data File',app.CurrentPathName);

            if app.filename == 0
                app.FileNameEditField.Value = 'No file is loaded';
                app.filename = app.LastFilename;
                app.filepath = app.LastFilepath;
            else
                app.LastFilename = app.filename;
                app.LastFilepath = app.filepath;
                app.CurrentPathName = app.filepath;

                if isempty(strfind(app.filename,'.dump')) == 1
                    app.Meas = readGwySimpleField([app.filepath app.filename]);
                    app.MeasData = app.Meas.Data;

                    app.XPixel = app.Meas.XReal*1e9/app.Meas.XRes;
                    app.YPixel = app.Meas.YReal*1e9/app.Meas.YRes;

                    app.XPixel = app.Meas.XReal*1e9/app.Meas.XRes; 
                    app.YPixel = app.Meas.YReal*1e9/app.Meas.YRes;

                    app.XAxData = linspace(app.Meas.XOffset,app.Meas.XOffset+app.Meas.XReal,app.Meas.XRes)*1e6;
                    app.YAxData = linspace(app.Meas.YOffset,app.Meas.YOffset+app.Meas.YReal,app.Meas.YRes)*1e6;
                else
                    app.Meas = readDUMP([app.filepath app.filename]);
                    app.MeasData = app.Meas.Data;
                
                    app.XPixel = app.Meas.xreal*1e9/app.Meas.xres; 
                    app.YPixel = app.Meas.yreal*1e9/app.Meas.yres;

                    app.XAxData = linspace(0,app.Meas.xreal,app.Meas.xres)*1e6;
                    app.YAxData = linspace(0,app.Meas.yreal,app.Meas.yres)*1e6;
                end

                
%                 app.XAxData = [1:app.Meas.XRes]*app.XPixel/1000;
%                 app.YAxData = [1:app.Meas.YRes]*app.YPixel/1000;

                try
                    [app.dataType, app.DataTypeBoolean] = SNOMCheckDataType(app.filename);
                catch
                    uialert(app.UIFigure,'Something went wrong load file again','WTF happened?')
                end
                app.FileNameEditField.Value = [app.dataType 'file has been loaded'];
                
                if app.DataTypeBoolean == 1
                    app.AmpLamp.Color = [0 1 0];
                    app.PhaseTopoLamp.Color = [1 1 1];
                    app.DataTypeSwitch_2.Value = 'Amp';
                    app.ColorCETmapEditField.Value = 'HEAT';
                else
                    app.PhaseTopoLamp.Color = [0 1 0];
                    app.AmpLamp.Color = [1 1 1];
                    app.DataTypeSwitch_2.Value = 'Phase';
                    app.ColorCETmapEditField.Value = 'NEADD';
                end

                %Initialize reseted mask
                app.Mask = true(size(app.MeasData));
                app.PointMask = false(size(app.MeasData));
                
                %Meshgrid a felületillesztésekhez
                [app.gridX,app.gridY]=meshgrid(1:size(app.MeasData,2),1:size(app.MeasData,1));
                
                ReDrawData(app);
                %axis(app.AxHandle, 'tight' , 'equal');
%                 app.FileNameEditField.Value = [app.filename ' is displayed'];
            end
        end

        % Button pushed function: SelectPointsButton
        function SelectPointsButtonPushed(app, event)
            app.PointROI1 = drawcircle(app.AxHandle);
            app.PointROI2 = drawcircle(app.AxHandle);
            app.PointROI3 = drawcircle(app.AxHandle);
            app.ShowBGButton.Enable = 'on';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            delete(app.PointROI1)
            delete(app.PointROI2)
            delete(app.PointROI3)
            
            if app.DataTypeBoolean == 1
                app.MeasData = app.MeasData./app.PlaneBG;
            elseif app.DataTypeBoolean == 0
                app.MeasData = app.MeasData-app.PlaneBG;
            end

            app.PointMask = false(size(app.MeasData));
            app.ApplyButton.Enable = 'off';
            app.ShowBGButton.Enable = 'off';

            UpDateFigure(app);
        end

        % Button pushed function: DrawMaskButton
        function DrawMaskButtonPushed(app, event)
            val = app.ShapeDropDown.Value;
            switch val
                case '1'
                    app.MaskROI = drawrectangle(app.AxHandle);
%                     app.ROI = roiTemp;
                case '2'
                    app.MaskROI = drawcircle(app.AxHandle);
%                     app.ROI = roiTemp;
                case '3'
                    app.MaskROI = drawpolygon(app.AxHandle);
            end
        end

        % Value changed function: ActivateCheckBox
        function ActivateCheckBoxValueChanged(app, event)
            value = app.ActivateCheckBox.Value;
            switch value
                case 1
                    app.DrawMaskButton.Enable = 1;
                    app.AddButton.Enable = 1;
                    app.SubtractButton.Enable = 1;
                    app.ResetButton.Enable = 1;
                    app.InvertButton.Enable = 1;
                    app.ImHandle.AlphaData = 0.5*~app.Mask+0.5;
                case 0
                    app.DrawMaskButton.Enable = 0;
                    app.AddButton.Enable = 0;
                    app.SubtractButton.Enable = 0;
                    app.ResetButton.Enable = 0;
                    app.InvertButton.Enable = 0;
                    app.ImHandle.AlphaData = 1;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            TempMask = createMask(app.MaskROI);
            if all(all(app.Mask)) == 1
                app.Mask = TempMask;
            else
                app.Mask = app.Mask|TempMask;
            end
            app.ImHandle.AlphaData = 0.5*~app.Mask+0.5;
            delete(app.MaskROI)
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
            TempMask = createMask(app.MaskROI);
            app.Mask = app.Mask.*~(app.Mask&TempMask);
            
            app.ImHandle.AlphaData = 0.5*~app.Mask+0.5;
            delete(app.MaskROI)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Mask = true(size(app.MeasData));
            app.ImHandle.AlphaData = 1;
        end

        % Button pushed function: InvertButton
        function InvertButtonPushed(app, event)
            app.Mask = ~app.Mask;
            app.ImHandle.AlphaData = 0.5*~app.Mask+0.5;
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            polytype = ['poly' num2str(app.xdegreeEditField.Value) num2str(app.ydegreeEditField.Value)];
            app.PolyBGZ = SNOMFitWithMask(app.gridX,app.gridY,app.MeasData,app.Mask,polytype);
            app.RemoveBGButton.Enable = 1;
            PlotTempFigure(app,app.PolyBGZ);
        end

        % Button pushed function: RemoveBGButton
        function RemoveBGButtonPushed(app, event)
            if app.DataTypeBoolean == 1
                app.MeasData = app.MeasData./app.PolyBGZ;
            elseif app.DataTypeBoolean == 0
                app.MeasData = app.MeasData-app.PolyBGZ;
            end
%             app.ImHandle.CData = app.MeasData;
            UpDateFigure(app);
            
            if app.RecordcorrectionsButton.Value == 1
                n=length(app.ProcessRecord);
                app.ProcessRecord(n+1).process = 'Polyfit';
                app.MaskContainer(:,:,end+1) = app.Mask;
                MaskIndx = size(app.MaskContainer,3);
                app.ProcessRecord(n+1).option = [num2str(app.xdegreeEditField.Value),num2str(app.ydegreeEditField.Value),num2str(MaskIndx)];
            end
            app.RemoveBGButton.Enable='off';
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            if app.DataTypeBoolean == 1
                switch app.MethodDropDown.Value
                    case '1'
                        app.RowCorrectedTemp = SNOMalignrows(app.MeasData,'Amp');
                    case '2'
                        app.RowCorrectedTemp = SNOMalignrowsMedDiff(app.MeasData,'Amp');
                end
            elseif app.DataTypeBoolean == 0
                switch app.MethodDropDown.Value
                    case '1'
                        app.RowCorrectedTemp = SNOMalignrows(app.MeasData,'Phase');
                    case '2'
                        app.RowCorrectedTemp = SNOMalignrowsMedDiff(app.MeasData,'Phase');
                end
            end
            
            app.ApplyRowCorrectionButton.Enable = 'on';
            PlotTempFigure(app,app.RowCorrectedTemp)
        end

        % Button pushed function: ApplyRowCorrectionButton
        function ApplyRowCorrectionButtonPushed(app, event)
            app.MeasData = app.RowCorrectedTemp;
            UpDateFigure(app);
            
            if app.RecordcorrectionsButton.Value == 1
                n=length(app.ProcessRecord);
                app.ProcessRecord(n+1).process = 'Alignrows';
                switch app.MethodDropDown.Value
                    case '1'
                        app.ProcessRecord(n+1).option = 'median';
                    case '2'
                        app.ProcessRecord(n+1).option = 'mediandiff';
                end
            end
            
            app.ApplyRowCorrectionButton.Enable = 'off';
        end

        % Button pushed function: DrawProfileButton
        function DrawProfileButtonPushed(app, event)
            app.ProfileFigHandle=figure; app.ProfileAxHandle=axes(app.ProfileFigHandle);
            app.ProfileROI = drawline(app.AxHandle);
            app.ProfileROI.UserData.OnCleanup = onCleanup(@() DeleteMarkers(app));
%             app.LineProfile = improfile(app.MeasData,app.ProfileROI.Position(:,1),app.ProfileROI.Position(:,2));
            app.ProfilePosition = app.ProfileROI.Position;

            [app.LineProfile,posMark1,posMark2] = SNOMExctractAvgProfile(app.MeasData,app.ProfilePosition,[min(app.XAxData) max(app.XAxData)],[min(app.YAxData) max(app.YAxData)],app.ProfileAvgSpinner.Value);
            app.LineMarker1 = drawline('Position',posMark1,'InteractionsAllowed','none');
            app.LineMarker2 = drawline('Position',posMark2,'InteractionsAllowed','none');
            
            plot(app.ProfileAxHandle,app.LineProfile)
            
            roiListener = addlistener(app.ProfileROI,'MovingROI',@(src,event) ProfileROIChange(app,src,event));
            
%             addlistener(app.ProfileROI,'ROIMoved',@(src,event) DeleteMarkers(app,src,event));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            assignin('base','linescan',app.LineProfile);
        end

        % Button pushed function: ApproachCurveButton
        function ApproachCurveButtonPushed(app, event)
            [filenameac,filepathac]=uigetfile({'*.*','All Files'},'Select Data File');
            if filenameac == 0
                app.FileNameEditField.Value = 'No file is loaded';
            else
                AC = readApproach([filepathac filenameac]);
                AC = ones(length(AC),12);
                [AC(:,1),AC(:,2),AC(:,3),AC(:,4),AC(:,5),AC(:,6),AC(:,7),AC(:,8),AC(:,9),AC(:,10),AC(:,11),AC(:,12)] = readApproach([filepathac filenameac]);
            end
            
            app.ApproachCurve.Depth = AC(:,1); app.ApproachCurve.Z = AC(:,2); app.ApproachCurve.M1A = AC(:,3); app.ApproachCurve.M1P = AC(:,4);
            app.ApproachCurve.O1A = AC(:,5); app.ApproachCurve.O1P = AC(:,6);
            app.ApproachCurve.O2A = AC(:,7); app.ApproachCurve.O2P = AC(:,8);
            app.ApproachCurve.O3A = AC(:,9); app.ApproachCurve.O3P = AC(:,10);
            app.ApproachCurve.O4A = AC(:,11); app.ApproachCurve.O4P = AC(:,12);
            
            app.ApproachApp = SNOMApproachApp(app);
        end

        % Button pushed function: HyperSpectralProcessingButton
        function HyperSpectralProcessingButtonPushed(app, event)
            try
            if app.AutomaticcorrectionsCheckBox.Value == 1
                app.HyperSpectralProcessingButton.Enable = 'off';
                app.HyperSpectralApp = SNOMHyperSpectralApp(app);
            else
                if isempty(app.TopoCube) == 1
                    uialert(app.UIFigure,'TopoCube dataset is empty!','Invalid Dataset');
                elseif isempty(app.HyperCube) == 1
                    uialert(app.UIFigure,'HyperCube dataset is empty!','Invalid Dataset');
                elseif sum(size(app.HyperCube,3)-size(app.TopoCube,3)) ~= 0
                    uialert(app.UIFigure,'TopoCube and HyperCube must be the same size!','Invalid Dataset');
                elseif size(app.HyperCube,3) < 2 || size(app.TopoCube,3) < 2
                    uialert(app.UIFigure,'TopoCube and HyperCube must contain at least two measurements!','Invalid Dataset');
                else
                    app.HyperSpectralProcessingButton.Enable = 'off';
                    app.HyperSpectralApp = SNOMHyperSpectralApp(app);
                end
            end
            catch
                uialert(app.UIFigure,'Load the first image of the set first!','No map is loaded');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try 
                close(app.FigHandle)
            catch
                warning('SNOMapp Figure window was already closed');
            end
            delete(app.ApproachApp);
            delete(app.HyperSpectralApp);
            delete(app)
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            app.Meas.Data = app.MeasData;
            assignin('base','meas',app.Meas);
%             assignin('base','map',app.MeasData);
        end

        % Button pushed function: CropMapButton
        function CropMapButtonPushed(app, event)
            cropfigh = figure;
            cropimh = axes(cropfigh);
            imagesc(cropimh,app.MeasData);
            CropROI = drawrectangle(cropimh);

            app.MeasData = imcrop(app.MeasData,CropROI.Position);
            app.Mask = imcrop(app.Mask,CropROI.Position);
            app.PointMask = false(size(app.MeasData));

            [app.gridX,app.gridY]=meshgrid(1:size(app.MeasData,2),1:size(app.MeasData,1));
            app.XAxData = app.XAxData(round(CropROI.Position(1)):(round(CropROI.Position(1))+round(CropROI.Position(3))));
            app.YAxData = app.YAxData(round(CropROI.Position(2)):(round(CropROI.Position(2))+round(CropROI.Position(4))));

            close(app.FigHandle)
            app.FigHandle = figure;
            app.AxHandle = axes(app.FigHandle);
            ReDrawData(app);
            delete(CropROI);
        end

        % Button pushed function: PolaritoninterferometryButton
        function PolaritoninterferometryButtonPushed(app, event)
            app.PolaritonApp = SNOMPolaritonApp(app);
        end

        % Button pushed function: RecondButton
        function RecondButtonPushed(app, event)
            ReconditionData(app)
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            if isempty(strfind(app.filename,'.dump')) == 1
                app.Meas = readGwySimpleField([app.filepath app.filename]);
            else
                app.Meas = readDUMP([app.filepath app.filename]);
            end
            app.MeasData = app.Meas.Data;
            
            app.XPixel = app.Meas.XReal*1e9/app.Meas.XRes; 
            app.YPixel = app.Meas.YReal*1e9/app.Meas.YRes;
            
%                 app.XAxData = [1:app.Meas.XRes]*app.XPixel/1000;
%                 app.YAxData = [1:app.Meas.YRes]*app.YPixel/1000;
            app.XAxData = linspace(app.Meas.XOffset,app.Meas.XOffset+app.Meas.XReal,app.Meas.XRes)*1e6;
            app.YAxData = linspace(app.Meas.YOffset,app.Meas.YOffset+app.Meas.YReal,app.Meas.YRes)*1e6;

            [app.dataType, app.DataTypeBoolean] = SNOMCheckDataType(app.filename);
            app.FileNameEditField.Value = [app.dataType 'file has been loaded'];
            
            if app.DataTypeBoolean == 1
                app.AmpLamp.Color = [0 1 0];
                app.PhaseTopoLamp.Color = [1 1 1];
                app.DataTypeSwitch_2.Value = 'Amp';
            else
                app.PhaseTopoLamp.Color = [0 1 0];
                app.AmpLamp.Color = [1 1 1];
                app.DataTypeSwitch_2.Value = 'Phase';
            end
            %Initialize reseted mask
            app.Mask = true(size(app.MeasData));
            app.PointMask = false(size(app.MeasData));
            
            %Meshgrid a felületillesztésekhez
            [app.gridX,app.gridY]=meshgrid(1:size(app.MeasData,2),1:size(app.MeasData,1));

            ReDrawData(app);
        end

        % Button pushed function: SetMapButton
        function SetMapButtonPushed(app, event)
            app.AxHandle.Colormap=colorcet(app.ColorCETmapEditField.Value);
            app.AxHandle.CLim = [app.RangeMinEditField.Value,app.RangeMaxEditField.Value];
        end

        % Value changed function: ScaleBarCheckBox
        function ScaleBarCheckBoxValueChanged(app, event)
            value = app.ScaleBarCheckBox.Value;
            ss = app.ScalebarEditField.Value;
            switch value
                case 1
                    x1 = min(app.XAxData)+0.1*(max(app.XAxData)-min(app.XAxData));
                    x2 = x1 + str2double(ss(1:(strfind(ss,' nm')-1)))*1e-3;
                    y = max(app.YAxData)-0.1*(max(app.YAxData)-min(app.YAxData));
                    app.scalebarh = line(app.AxHandle,[x1 x2],[y,y]);
                    app.scalebarh.Color = "red";
                    app.scalebarh.LineWidth = 2;
                    app.scalebartexth = text(app.AxHandle,x1,y,ss);
                    app.scalebartexth.Color = 'red';
                    app.scalebartexth.Position(2) = app.scalebartexth.Position(2)+(app.scalebartexth.FontSize+app.scalebarh.LineWidth)*app.YPixel*1e-3;
                case 0
                    if ~isempty(app.scalebarh) == 0

                    else
                        if isvalid(app.scalebarh) == 1
                            delete(app.scalebarh);
                            delete(app.scalebartexth);
                        end
                    end

            end
        end

        % Button pushed function: AddtoDataSetButton
        function AddtoDataSetButtonPushed(app, event)
            value = app.DataTypeSwitch.Value;
            wnvalue = SNOMGetWavenumberFromFileName(app.filename);
            if wnvalue == -1
                flist = dir(app.filepath);
                for i = 1:length(flist)
                    if contains(flist(i).name,'.txt') == 1
                        wnvalue = readNeaInfoTXT([app.filepath flist(i).name]);
                    else
                        uialert(app.UIFigure,'No wavelength information!','Dataname and info file doesnt contain wavelength info!')
                    end
                end
            end

            switch value
                case 'Topo'
                    if ~any(app.TopoWNs(:) == wnvalue)
                        if isempty(app.TopoWNs) == 1
                            app.TopoWNs(1) = wnvalue;
                        else
                            app.TopoWNs(end+1) = wnvalue;
                        end
                        if isempty(app.TopoCube) == 1
                            app.TopoCube(:,:,1) = app.MeasData;
                        else
                            app.TopoCube(:,:,end+1) = app.MeasData;
                        end
                        app.DataSetViewerDropDown.Items = strsplit(num2str(app.TopoWNs));
                        app.DataSetViewerDropDown.ItemsData = app.TopoWNs;
                        app.DataSetViewerDropDown.Value = app.TopoWNs(end);
                    else
                    end

                case 'Optic'
                    if ~any(app.HyperWNs(:) == wnvalue)
                        if isempty(app.HyperWNs) == 1
                            app.HyperWNs(1) = wnvalue;
                        else
                            app.HyperWNs(end+1) = wnvalue;
                        end
                        if isempty(app.HyperCube) == 1
                            app.HyperCube(:,:,1) = app.MeasData;
                        else
                            app.HyperCube(:,:,end+1) = app.MeasData;
                        end
                        app.DataSetViewerDropDown.Items = strsplit(num2str(app.HyperWNs));
                        app.DataSetViewerDropDown.ItemsData = app.HyperWNs;
                        app.DataSetViewerDropDown.Value = app.HyperWNs(end);
                    else
                    end
            end
        end

        % Value changed function: AutomaticcorrectionsCheckBox
        function AutomaticcorrectionsCheckBoxValueChanged(app, event)
%             value = app.AutomaticcorrectionsCheckBox.Value;
%             if value == 1
%                 app.AddtoDataSetButton.Enable = 'off';
%                 app.RecordcorrectionsButton.Enable = 'on';
%             else
%                 app.AddtoDataSetButton.Enable = 'on';
%                 app.RecordcorrectionsButton.Enable = 'off';
%             end
        end

        % Button pushed function: LoadDataSetButton
        function LoadDataSetButtonPushed(app, event)
            value = app.DataTypeSwitch.Value;
            [fname, fpath] = uigetfile({'*.mat','MATLAB files'},'Select TopoCube dataset file');
            switch value
                case 'Topo'
                    try
                        temp = load([fpath, fname]);
                        app.TopoCube = temp.Data;
                        app.TopoWNs = temp.wn;
                        app.FileNameEditField.Value = ['TopoCube named: ',fname,' was loaded'];
                        app.DataSetViewerDropDown.Items = strsplit(num2str(app.TopoWNs));
                        app.DataSetViewerDropDown.ItemsData = app.TopoWNs;
                        app.DataSetViewerDropDown.Value = app.TopoWNs(end);
                    catch
                    end
                case 'Optic'
                    try
                        temp = load([fpath, fname]);
                        app.HyperCube = temp.Data;
                        app.HyperWNs = temp.wn;
                        app.FileNameEditField.Value = ['HyperCube named: ',fname,' was loaded'];
                        app.DataSetViewerDropDown.Items = strsplit(num2str(app.HyperWNs));
                        app.DataSetViewerDropDown.ItemsData = app.HyperWNs;
                        app.DataSetViewerDropDown.Value = app.HyperWNs(end);
                    catch
                    end
            end
        end

        % Button pushed function: ClearDataSetButton
        function ClearDataSetButtonPushed(app, event)
            value = app.DataTypeSwitch.Value;
            switch value
                case 'Topo'
                    app.TopoCube = [];
                case 'Optic'
                    app.HyperCube = [];
            end
        end

        % Button pushed function: SaveDataSetButton
        function SaveDataSetButtonPushed(app, event)
            value = app.DataTypeSwitch.Value;
            switch value
                case 'Topo'
%                     TopoStruct.wn = app.TopoWNs;
%                     TopoStruct.Data = app.TopoCube;
%                     assignin('base','TopoStruct',TopoStruct);
                    wn = app.TopoWNs;
                    Data = app.TopoCube;
                    uisave({'wn','Data'},'TopoStruct');
                case 'Optic'
                    wn = app.HyperWNs;
                    Data = app.HyperCube;
                    uisave({'wn','Data'},'HyperStruct');
            end
        end

        % Value changed function: ProfileAvgSpinner
        function ProfileAvgSpinnerValueChanged(app, event)
            if isempty(app.ProfileROI) == 0
                if isvalid(app.ProfileROI) == 1
                    ProfileROIChange(app,event)
                else
                end
            else
            end
        end

        % Value changed function: DataTypeSwitch
        function DataTypeSwitchValueChanged(app, event)
            value = app.DataTypeSwitch.Value;
            switch value
                case 'Topo'
                    if isempty(app.TopoWNs) == 1
                        app.DataSetViewerDropDown.Items = {};
                        app.DataSetViewerDropDown.ItemsData = [];
                    else
                        app.DataSetViewerDropDown.Items = strsplit(num2str(app.TopoWNs));
                        app.DataSetViewerDropDown.ItemsData = app.TopoWNs;
                        app.DataSetViewerDropDown.Value = app.TopoWNs(end);
                    end
                case 'Optic'
                    if isempty(app.HyperWNs) == 1
                        app.DataSetViewerDropDown.Items = {};
                        app.DataSetViewerDropDown.ItemsData = [];
                    else
                        app.DataSetViewerDropDown.Items = strsplit(num2str(app.HyperWNs));
                        app.DataSetViewerDropDown.ItemsData = app.HyperWNs;
                        app.DataSetViewerDropDown.Value = app.HyperWNs(end);
                    end
            end
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            value = app.DataTypeSwitch.Value;
            switch value
                case 'Topo'
                    if isempty(app.TopoWNs) == 1
                    else
                        MeasIndx = find(app.TopoWNs == app.DataSetViewerDropDown.Value);
                        PlotTempFigure(app,app.TopoCube(:,:,MeasIndx))
                        title(['Topography from Dataset @', num2str(app.TopoWNs(MeasIndx)), 'cm-1']);
                        colorcet('GRAY')
                    end
                case 'Optic'
                    if isempty(app.HyperWNs) == 1
                    else
                        MeasIndx = find(app.HyperWNs == app.DataSetViewerDropDown.Value);
                        PlotTempFigure(app,app.HyperCube(:,:,MeasIndx))
                        title(['Optical image from Dataset @', num2str(app.HyperWNs(MeasIndx)), 'cm-1']);
                    end
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            value = app.DataTypeSwitch.Value;
            switch value
                case 'Topo'
                    if isempty(app.TopoWNs) == 1
                    else
                        MeasIndx = find(app.TopoWNs == app.DataSetViewerDropDown.Value);
                        app.TopoWNs(MeasIndx) = [];
                        app.TopoCube(:,:,MeasIndx) = [];
                        app.DataSetViewerDropDown.Items = strsplit(num2str(app.TopoWNs));
                        app.DataSetViewerDropDown.ItemsData = app.TopoWNs;
%                         app.DataSetViewerDropDown.Value = app.TopoWNs(end);
                    end
                case 'Optic'
                    if isempty(app.HyperWNs) == 1
                    else
                        MeasIndx = find(app.HyperWNs == app.DataSetViewerDropDown.Value);
                        app.HyperWNs(MeasIndx) = [];
                        app.HyperCube(:,:,MeasIndx) = [];
                        app.DataSetViewerDropDown.Items = strsplit(num2str(app.HyperWNs));
                        app.DataSetViewerDropDown.ItemsData = app.HyperWNs;
%                         app.DataSetViewerDropDown.Value = app.HyperWNs(end);
                    end
            end
        end

        % Button pushed function: InvertDataButton
        function InvertDataButtonPushed(app, event)
            app.MeasData = -1*app.MeasData;
            UpDateFigure(app);
        end

        % Button pushed function: ShowBGButton
        function ShowBGButtonPushed(app, event)
            TempMask1 = createMask(app.PointROI1);
            TempMask2 = createMask(app.PointROI2);
            TempMask3 = createMask(app.PointROI3);
            
            app.PointMask = app.PointMask|TempMask1;
            app.PointMask = app.PointMask|TempMask2;
            app.PointMask = app.PointMask|TempMask3;
            
            app.PlaneBG = SNOMFitWithMask(app.gridX,app.gridY,app.MeasData,app.PointMask,'poly11');

            if app.DataTypeBoolean == 1
                tempdata = app.MeasData./app.PlaneBG;
            elseif app.DataTypeBoolean == 0
                tempdata = app.MeasData-app.PlaneBG;
            end

            PlotTempFigure(app,app.PlaneBG);
            title('Background plane');
            PlotTempFigure(app,tempdata);
            title('The corrected image will look like this!')

            app.ApplyButton.Enable = 'on';
        end

        % Value changed function: DataTypeSwitch_2
        function DataTypeSwitch_2ValueChanged(app, event)
            value = app.DataTypeSwitch_2.Value;
            switch value
                case 'Amp'
                    app.DataTypeBoolean = 1;
                    app.dataType = 'A';
                    app.FileNameEditField.Value = 'Datatype changed to A';
                case 'Phase'
                    app.DataTypeBoolean = 0;
                    app.dataType = 'P';
                    app.FileNameEditField.Value = 'Datatype changed to P';
            end

            if app.DataTypeBoolean == 1
                app.AmpLamp.Color = [0 1 0];
                app.PhaseTopoLamp.Color = [1 1 1];
            else
                app.PhaseTopoLamp.Color = [0 1 0];
                app.AmpLamp.Color = [1 1 1];
            end
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            nHarmonic = str2num(app.ChooseharmonicDropDown.Value);

            refFilename = SNOMchangeFilename(app.filename,nHarmonic);

            if isempty(strfind(app.filename,'.dump')) == 1
                refMeas = readGwySimpleField([app.filepath refFilename]);
                refData = refMeas.Data;
                if app.DataTypeBoolean == 1
                    tempdata = app.MeasData./refData;
                elseif app.DataTypeBoolean == 0
                    tempdata = app.MeasData-refData;
                end

                PlotTempFigure(app,tempdata);
                title('The corrected image will look like this!')
            else
                refMeas = readDUMP([app.filepath refFilename]);
                refData = refMeas.Data;
                 if app.DataTypeBoolean == 1
                    tempdata = app.MeasData./refData;
                elseif app.DataTypeBoolean == 0
                    tempdata = app.MeasData-refData;
                 end
            end
            PlotTempFigure(app,tempdata);
            title('The corrected image will look like this!')
            app.ApplySelfReferencce.Enable = 'on';
        end

        % Button pushed function: ApplySelfReferencce
        function ApplySelfReferencceButtonPushed(app, event)
            nHarmonic = str2num(app.ChooseharmonicDropDown.Value);

            refFilename = SNOMchangeFilename(app.filename,nHarmonic);

            if isempty(strfind(app.filename,'.dump')) == 1
                refMeas = readGwySimpleField([app.filepath refFilename]);
                refData = refMeas.Data;
                if app.DataTypeBoolean == 1
                    app.MeasData = app.MeasData./refData;
                elseif app.DataTypeBoolean == 0
                    app.MeasData = app.MeasData-refData;
                end
                UpDateFigure(app);
            else
                refMeas = readDUMP([app.filepath refFilename]);
                refData = refMeas.Data;
                if app.DataTypeBoolean == 1
                    app.MeasData = app.MeasData./refData;
                elseif app.DataTypeBoolean == 0
                    app.MeasData = app.MeasData-refData;
                end
            end
            UpDateFigure(app);
            app.ApplySelfReferencce.Enable = 'off';
        end

        % Button pushed function: RotatePhaseButton
        function RotatePhaseButtonPushed(app, event)
            if app.DataTypeBoolean == 1 || isempty(strfind(app.dataType,'Z')) == 0
                uialert(app.UIFigure,'This is not a phase map, load the correct data!','Incorrect datatype!')
            else
                try
                    filenameAmp = SNOMchangeFilenameAvsP(app.filename,'A');
                    tempAmp = readGwySimpleField([app.filepath, filenameAmp]);
                    tempAmp = tempAmp.Data;
                    angleToRotate = deg2rad(app.PhaseRotateEditField.Value);
                    app.MeasData = angle(tempAmp.*exp(1i*app.MeasData).*exp(1i*angleToRotate));
                    UpDateFigure(app);
                catch
                    msg = 'Amplitude file was not found! Please choose the corresponding amplitude file!';
                    selection = uiconfirm(app.UIFigure,msg,'Open amplitude file');
                    switch selection
                        case 'OK'
                            [filenameAmp,filepathAmp]=uigetfile({'*.gsf;*.dump','NeaFILEs'},'Select Data File',app.CurrentPathName);
                            tempAmp = readGwySimpleField([filepathAmp filenameAmp]);
                            tempAmp = tempAmp.Data;
    
                            angleToRotate = deg2rad(app.PhaseRotateEditField.Value);
                            app.MeasData = angle(tempAmp.*exp(1i*app.MeasData).*exp(1i*angleToRotate));
                            UpDateFigure(app);
                        case 'Cancel'
                            return
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Editable = 'off';
            app.PlanelevelingPanel = uipanel(app.UIFigure);
            app.PlanelevelingPanel.Title = 'Plane leveling';
            app.ApplyButton = uibutton(app.PlanelevelingPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.SelectPointsButton = uibutton(app.PlanelevelingPanel, 'push');
            app.SelectPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsButtonPushed, true);
            app.SelectPointsButton.Text = 'Select Points';
            app.ShowBGButton = uibutton(app.PlanelevelingPanel, 'push');
            app.ShowBGButton.ButtonPushedFcn = createCallbackFcn(app, @ShowBGButtonPushed, true);
            app.ShowBGButton.Enable = 'off';
            app.ShowBGButton.Text = 'Show BG';
            app.MaskingPanel = uipanel(app.UIFigure);
            app.MaskingPanel.Title = 'Masking';
            app.ActivateCheckBox = uicheckbox(app.MaskingPanel);
            app.ActivateCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivateCheckBoxValueChanged, true);
            app.ActivateCheckBox.Text = 'Activate';
            app.DrawMaskButton = uibutton(app.MaskingPanel, 'push');
            app.DrawMaskButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMaskButtonPushed, true);
            app.DrawMaskButton.Enable = 'off';
            app.DrawMaskButton.Text = 'Draw Mask';
            app.AddButton = uibutton(app.MaskingPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.SubtractButton = uibutton(app.MaskingPanel, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Enable = 'off';
            app.SubtractButton.Text = 'Subtract';
            app.ResetButton = uibutton(app.MaskingPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.ShapeDropDownLabel = uilabel(app.MaskingPanel);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.MaskingPanel);
            app.ShapeDropDown.Items = {'Rectangle', 'Circle', 'Polygon'};
            app.ShapeDropDown.ItemsData = {'1', '2', '3'};
            app.ShapeDropDown.Value = '1';
            app.InvertButton = uibutton(app.MaskingPanel, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.InvertButton.Enable = 'off';
            app.InvertButton.Text = 'Invert';
            app.PolynomialBGremovalPanel = uipanel(app.UIFigure);
            app.PolynomialBGremovalPanel.Title = 'Polynomial BG removal';
            app.xdegreeEditFieldLabel = uilabel(app.PolynomialBGremovalPanel);
            app.xdegreeEditFieldLabel.Text = 'x degree';
            app.xdegreeEditField = uieditfield(app.PolynomialBGremovalPanel, 'numeric');
            app.xdegreeEditField.Limits = [1 5];
            app.xdegreeEditField.RoundFractionalValues = 'on';
            app.xdegreeEditField.Value = 1;
            app.ydegreeEditFieldLabel = uilabel(app.PolynomialBGremovalPanel);
            app.ydegreeEditFieldLabel.Text = 'y degree';
            app.ydegreeEditField = uieditfield(app.PolynomialBGremovalPanel, 'numeric');
            app.ydegreeEditField.Limits = [1 5];
            app.ydegreeEditField.RoundFractionalValues = 'on';
            app.ydegreeEditField.Value = 1;
            app.FitButton = uibutton(app.PolynomialBGremovalPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.RemoveBGButton = uibutton(app.PolynomialBGremovalPanel, 'push');
            app.RemoveBGButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBGButtonPushed, true);
            app.RemoveBGButton.Enable = 'off';
            app.RemoveBGButton.Text = 'Remove BG';
            app.AlignRowsPanel = uipanel(app.UIFigure);
            app.AlignRowsPanel.Title = 'Align Rows';
            app.MethodDropDownLabel = uilabel(app.AlignRowsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.AlignRowsPanel);
            app.MethodDropDown.Items = {'Median', 'Median of diff.', ''};
            app.MethodDropDown.ItemsData = {'1', '2'};
            app.MethodDropDown.Value = '1';
            app.AlignButton = uibutton(app.AlignRowsPanel, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Text = 'Align';
            app.ApplyRowCorrectionButton = uibutton(app.AlignRowsPanel, 'push');
            app.ApplyRowCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyRowCorrectionButtonPushed, true);
            app.ApplyRowCorrectionButton.Enable = 'off';
            app.ApplyRowCorrectionButton.Text = 'Apply Row Correction';
            app.LineProfilePanel = uipanel(app.UIFigure);
            app.LineProfilePanel.Title = 'Line Profile';
            app.DrawProfileButton = uibutton(app.LineProfilePanel, 'push');
            app.DrawProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DrawProfileButtonPushed, true);
            app.DrawProfileButton.Text = 'Draw Profile';
            app.SaveButton = uibutton(app.LineProfilePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AvgSpinner_2Label = uilabel(app.LineProfilePanel);
            app.AvgSpinner_2Label.Text = 'Avg';
            app.ProfileAvgSpinner = uispinner(app.LineProfilePanel);
            app.ProfileAvgSpinner.Limits = [1 Inf];
            app.ProfileAvgSpinner.RoundFractionalValues = 'on';
            app.ProfileAvgSpinner.ValueChangedFcn = createCallbackFcn(app, @ProfileAvgSpinnerValueChanged, true);
            app.ProfileAvgSpinner.Value = 1;
            app.AmpLabel = uilabel(app.UIFigure);
            app.AmpLabel.Text = 'Amp';
            app.AmpLamp = uilamp(app.UIFigure);
            app.PhaseTopoLampLabel = uilabel(app.UIFigure);
            app.PhaseTopoLampLabel.Text = 'Phase/Topo';
            app.PhaseTopoLamp = uilamp(app.UIFigure);
            app.ReloadButton = uibutton(app.UIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.ApproachCurveButton = uibutton(app.UIFigure, 'push');
            app.ApproachCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ApproachCurveButtonPushed, true);
            app.ApproachCurveButton.Enable = 'off';
            app.ApproachCurveButton.Text = 'Approach Curve';
            app.PolaritoninterferometryButton = uibutton(app.UIFigure, 'push');
            app.PolaritoninterferometryButton.ButtonPushedFcn = createCallbackFcn(app, @PolaritoninterferometryButtonPushed, true);
            app.PolaritoninterferometryButton.Enable = 'off';
            app.PolaritoninterferometryButton.Text = 'Polariton interferometry';
            app.RecondButton = uibutton(app.UIFigure, 'push');
            app.RecondButton.ButtonPushedFcn = createCallbackFcn(app, @RecondButtonPushed, true);
            app.RecondButton.Text = 'Recond';
            app.HyperspectraldataprocessPanel = uipanel(app.UIFigure);
            app.HyperspectraldataprocessPanel.Title = 'Hyperspectral data process';
            app.HyperSpectralProcessingButton = uibutton(app.HyperspectraldataprocessPanel, 'push');
            app.HyperSpectralProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @HyperSpectralProcessingButtonPushed, true);
            app.HyperSpectralProcessingButton.Text = 'HyperSpectral Processing';
            app.RecordcorrectionsButton = uibutton(app.HyperspectraldataprocessPanel, 'state');
            app.RecordcorrectionsButton.Enable = 'off';
            app.RecordcorrectionsButton.Text = 'Record corrections';
            app.AddtoDataSetButton = uibutton(app.HyperspectraldataprocessPanel, 'push');
            app.AddtoDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoDataSetButtonPushed, true);
            app.AddtoDataSetButton.Text = 'Add to DataSet';
            app.AutomaticcorrectionsCheckBox = uicheckbox(app.HyperspectraldataprocessPanel);
            app.AutomaticcorrectionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticcorrectionsCheckBoxValueChanged, true);
            app.AutomaticcorrectionsCheckBox.Enable = 'off';
            app.AutomaticcorrectionsCheckBox.Text = 'Automatic corrections';
            app.DataTypeSwitchLabel = uilabel(app.HyperspectraldataprocessPanel);
            app.DataTypeSwitchLabel.Text = 'Data Type';
            app.DataTypeSwitch = uiswitch(app.HyperspectraldataprocessPanel, 'slider');
            app.DataTypeSwitch.Items = {'Topo', 'Optic'};
            app.DataTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @DataTypeSwitchValueChanged, true);
            app.DataTypeSwitch.Value = 'Topo';
            app.LoadDataSetButton = uibutton(app.HyperspectraldataprocessPanel, 'push');
            app.LoadDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataSetButtonPushed, true);
            app.LoadDataSetButton.Text = 'Load DataSet';
            app.SaveDataSetButton = uibutton(app.HyperspectraldataprocessPanel, 'push');
            app.SaveDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataSetButtonPushed, true);
            app.SaveDataSetButton.Text = 'Save DataSet';
            app.ClearDataSetButton = uibutton(app.HyperspectraldataprocessPanel, 'push');
            app.ClearDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataSetButtonPushed, true);
            app.ClearDataSetButton.Text = 'Clear DataSet';
            app.DataSetViewerDropDownLabel = uilabel(app.HyperspectraldataprocessPanel);
            app.DataSetViewerDropDownLabel.Text = 'DataSet Viewer';
            app.DataSetViewerDropDown = uidropdown(app.HyperspectraldataprocessPanel);
            app.DataSetViewerDropDown.Items = {};
            app.DataSetViewerDropDown.Value = {};
            app.ShowButton = uibutton(app.HyperspectraldataprocessPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.DeleteButton = uibutton(app.HyperspectraldataprocessPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.DisplaymappropertiesPanel = uipanel(app.UIFigure);
            app.DisplaymappropertiesPanel.Title = 'Display map properties';
            app.ScalebarnmEditFieldLabel = uilabel(app.DisplaymappropertiesPanel);
            app.ScalebarnmEditFieldLabel.Text = 'Scalebar (nm)';
            app.ScalebarEditField = uieditfield(app.DisplaymappropertiesPanel, 'text');
            app.ScalebarEditField.Value = '100 nm';
            app.ScaleBarCheckBox = uicheckbox(app.DisplaymappropertiesPanel);
            app.ScaleBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleBarCheckBoxValueChanged, true);
            app.ScaleBarCheckBox.Text = '';
            app.ColorCETmapEditFieldLabel = uilabel(app.DisplaymappropertiesPanel);
            app.ColorCETmapEditFieldLabel.Text = 'ColorCETmap';
            app.ColorCETmapEditField = uieditfield(app.DisplaymappropertiesPanel, 'text');
            app.ColorCETmapEditField.Value = 'NEADD';
            app.SetMapButton = uibutton(app.DisplaymappropertiesPanel, 'push');
            app.SetMapButton.ButtonPushedFcn = createCallbackFcn(app, @SetMapButtonPushed, true);
            app.SetMapButton.Text = 'Set Map';
            app.RangeMinEditFieldLabel = uilabel(app.DisplaymappropertiesPanel);
            app.RangeMinEditFieldLabel.Text = 'Range Min';
            app.RangeMinEditField = uieditfield(app.DisplaymappropertiesPanel, 'numeric');
            app.RangeMaxEditFieldLabel = uilabel(app.DisplaymappropertiesPanel);
            app.RangeMaxEditFieldLabel.Text = 'Range Max';
            app.RangeMaxEditField = uieditfield(app.DisplaymappropertiesPanel, 'numeric');
            app.GeneralPanel = uipanel(app.UIFigure);
            app.GeneralPanel.Title = 'General';
            app.CropMapButton = uibutton(app.GeneralPanel, 'push');
            app.CropMapButton.ButtonPushedFcn = createCallbackFcn(app, @CropMapButtonPushed, true);
            app.CropMapButton.Text = 'Crop Map';
            app.SaveDataButton = uibutton(app.GeneralPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.OtheroperationsPanel = uipanel(app.UIFigure);
            app.OtheroperationsPanel.Title = 'Other operations';
            app.InvertDataButton = uibutton(app.OtheroperationsPanel, 'push');
            app.InvertDataButton.ButtonPushedFcn = createCallbackFcn(app, @InvertDataButtonPushed, true);
            app.InvertDataButton.Text = 'Invert Data';
            app.PhaseRotateEditField = uieditfield(app.OtheroperationsPanel, 'numeric');
            app.PhaseRotateEditField.Tooltip = {'Put here to rotate the values of phase images by the given value (degrees). It gets rid of the -pi/pi phase jumpes caused by atan2'};
            app.PhaseRotateEditField.Value = 90;
            app.RotatePhaseButton = uibutton(app.OtheroperationsPanel, 'push');
            app.RotatePhaseButton.ButtonPushedFcn = createCallbackFcn(app, @RotatePhaseButtonPushed, true);
            app.RotatePhaseButton.Text = 'Rotate Phase';
            app.DataTypeSwitch_2Label = uilabel(app.UIFigure);
            app.DataTypeSwitch_2Label.Text = 'Data Type';
            app.DataTypeSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.DataTypeSwitch_2.Items = {'Amp', 'Phase'};
            app.DataTypeSwitch_2.ValueChangedFcn = createCallbackFcn(app, @DataTypeSwitch_2ValueChanged, true);
            app.DataTypeSwitch_2.Value = 'Amp';
            app.SelfreferencePanel = uipanel(app.UIFigure);
            app.SelfreferencePanel.Title = 'Self-reference';
            app.NormalizeButton = uibutton(app.SelfreferencePanel, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize';
            app.ChooseharmonicDropDownLabel = uilabel(app.SelfreferencePanel);
            app.ChooseharmonicDropDownLabel.Text = 'Choose harmonic';
            app.ChooseharmonicDropDown = uidropdown(app.SelfreferencePanel);
            app.ChooseharmonicDropDown.Items = {'O1', 'O2', 'O3', 'O4'};
            app.ChooseharmonicDropDown.ItemsData = {'1', '2', '3', '4'};
            app.ChooseharmonicDropDown.Value = '1';
            app.ApplySelfReferencce = uibutton(app.SelfreferencePanel, 'push');
            app.ApplySelfReferencce.ButtonPushedFcn = createCallbackFcn(app, @ApplySelfReferencceButtonPushed, true);
            app.ApplySelfReferencce.Enable = 'off';
            app.ApplySelfReferencce.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNOMapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end